classdef appReto < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        DatosCalculadosPanel            matlab.ui.container.Panel
        VelocidadEditField              matlab.ui.control.NumericEditField
        VelocidadEditFieldLabel         matlab.ui.control.Label
        AlcanceMxEditField              matlab.ui.control.NumericEditField
        AlcanceMxEditFieldLabel         matlab.ui.control.Label
        AlturaMaxEditField              matlab.ui.control.NumericEditField
        AlturaMaxEditFieldLabel         matlab.ui.control.Label
        TiempototalEditField            matlab.ui.control.NumericEditField
        TiempototalEditFieldLabel       matlab.ui.control.Label
        ValoresInicialesPanel           matlab.ui.container.Panel
        PosyEditField                   matlab.ui.control.NumericEditField
        PosyEditFieldLabel              matlab.ui.control.Label
        PosXEditField                   matlab.ui.control.NumericEditField
        PosXLabel                       matlab.ui.control.Label
        PasosEditField                  matlab.ui.control.NumericEditField
        PasosEditFieldLabel             matlab.ui.control.Label
        DelayEditField                  matlab.ui.control.NumericEditField
        DelayEditFieldLabel             matlab.ui.control.Label
        MasaEditField                   matlab.ui.control.NumericEditField
        MasaEditFieldLabel              matlab.ui.control.Label
        CoeficienteArrastreEditField    matlab.ui.control.NumericEditField
        CoeficienteArrastreEditFieldLabel  matlab.ui.control.Label
        DensidaddelAireEditField        matlab.ui.control.NumericEditField
        DensidaddelAireEditFieldLabel   matlab.ui.control.Label
        VelocidadInicialEditField       matlab.ui.control.NumericEditField
        VelocidadInicialEditFieldLabel  matlab.ui.control.Label
        AnguloInicialEditField          matlab.ui.control.NumericEditField
        AnguloInicialLabel              matlab.ui.control.Label
        DiametroProyectilEditField      matlab.ui.control.NumericEditField
        DiametroProyectilEditFieldLabel  matlab.ui.control.Label
        INICIOButton                    matlab.ui.control.Button
        UIAxes                          matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: INICIOButton
        function INICIOButtonPushed(app, event)
            %% Programación del Botón Principal

            % Configuración de parámetros iniciales
            gravedad = -9.80;  % Gravedad
            velocidadInical = app.VelocidadInicialEditField.Value;  % Velocidad inicial
            diametro = app.DiametroProyectilEditField.Value;  % Diámetro del proyectil
            densidad = app.DensidaddelAireEditField.Value;  % Densidad del aire
            coeficienteArrastre = app.CoeficienteArrastreEditField.Value;  % Coeficiente de arrastre
            m = app.MasaEditField.Value;  % Masa del proyectil
            grados0 = app.AnguloInicialEditField.Value;  % Ángulo inicial
            diferencialT = app.DelayEditField.Value;  % Intervalo de tiempo
            n = app.PasosEditField.Value;  % Número de pasos
            posicionX = app.PosXEditField.Value;  % Posición inicial en el eje X
            posicionY = app.PosyEditField.Value;  % Posición inicial en el eje Y
            matrizX = zeros(n, 4);  % Matriz para datos de posición en X
            matrizY = zeros(n, 4);  % Matriz para datos de posición en Y

            % Cálculos iniciales
            areaObjeto = (3.1416 * diametro * diametro) / 4;  % Área del objeto
            coeficienteFinal = 0.5 * densidad * coeficienteArrastre * areaObjeto;  % Coeficiente final de arrastre
            radianes = deg2rad(grados0);  % Conversión de grados a radianes

            Vox = velocidadInical * cos(radianes);  % Componente X de la velocidad inicial
            Voy = velocidadInical * sin(radianes);  % Componente Y de la velocidad inicial

            % Se usan las fórmulas para el cálculo de la aceleración en X y
            % en Y
            aceleracionX = (-(coeficienteFinal / m) * Vox * sqrt((Vox * Vox) + (Voy * Voy)));  % Aceleración en X
            aceleracionY = (gravedad - (coeficienteFinal / m) * Voy * sqrt((Vox * Vox) + (Voy * Voy)));  % Aceleración en Y

            % Inicialización de datos en las primeras filas de las matrices
            indiceFila = 1;
            for i = 0:diferencialT:n
                matrizX(indiceFila, 1) = i;
                matrizY(indiceFila, 1) = i;
                indiceFila = indiceFila + 1;
            end

            matrizX(1, 2) = posicionX;
            matrizX(1, 3) = Vox;
            matrizX(1, 4) = aceleracionX;

            matrizY(1, 2) = posicionY;
            matrizY(1, 3) = Voy;
            matrizY(1, 4) = aceleracionY;

            % Cálculos de posición, velocidad y aceleración en cada paso de tiempo
            indiceFilas = 2;
            for i = 3:indiceFila
                % Cálculos en el eje X
                posicionX = posicionX + (diferencialT * Vox);
                Vox = Vox + (diferencialT * aceleracionX);

                % Cálculos en el eje Y
                posicionY = posicionY + (diferencialT * Voy);
                Voy = Voy + (diferencialT * aceleracionY);

                aceleracionX = (-(coeficienteFinal / m) * Vox * sqrt((Vox * Vox) + (Voy * Voy)));
                aceleracionY = (gravedad - (coeficienteFinal / m) * Voy * sqrt((Vox * Vox) + (Voy * Voy)));

                % Almacenar datos en las matrices
                matrizX(indiceFilas, 2) = posicionX;
                matrizX(indiceFilas, 3) = Vox;
                matrizX(indiceFilas, 4) = aceleracionX;

                matrizY(indiceFilas, 2) = posicionY;
                matrizY(indiceFilas, 3) = Voy;
                app.VelocidadEditField.Value= aceleracionY;

                indiceFilas = indiceFilas + 1;
            end

            % Mostrar las matrices en la consola
            disp(matrizX);
            disp(matrizY);

            % Graficar la trayectoria en el eje de la interfaz
            plot(app.UIAxes, matrizX(:, 2), matrizY(:, 2), 'b-');

            % Personalizar la gráfica en el UIAxes
            xlabel(app.UIAxes, 'Posición en el eje X');
            ylabel(app.UIAxes, 'Posición en el eje Y');
            title(app.UIAxes, 'Trayectoria del proyectil');
            % Graficar la trayectoria inicial con un punto en rojo
            trayectoria = plot(app.UIAxes, NaN, NaN, 'r-');
            hold(app.UIAxes, 'on');

            % Inicializar los puntos de la trayectoria tocados con círculos verdes
            puntosTocados = plot(app.UIAxes, NaN, NaN, 'go');

            % Calcular el tiempo total de la animación basado en el número de pasos (n)
            tiempoTotalAnimacion = matrizX(end, 1);

            % Calcular el intervalo de tiempo y la pausa para tener una animación de tiempo total específico
            duracionTotal = tiempoTotalAnimacion; % La animación durará el mismo tiempo que la simulación
            diferencialT = duracionTotal / size(matrizX, 1); % Intervalo de tiempo para cada paso
            pausaDuracion = diferencialT; % Duración de la pausa entre cada paso

            % Animación de la trayectoria
            for i = 1:size(matrizX, 1)
                % Actualizar la posición de la trayectoria
                set(trayectoria, 'XData', matrizX(1:i, 2), 'YData', matrizY(1:i, 2));
    
                % Actualizar la posición de los puntos tocados
                set(puntosTocados, 'XData', matrizX(i, 2), 'YData', matrizY(i, 2));
    
                % Actualizar la vista de la gráfica
                drawnow;
    
                % Pausa para crear la animación con una duración específica
                pause(pausaDuracion);
            end
            % Configurar el aspecto de la gráfica en el UIAxes
            grid(app.UIAxes, 'on');
            axis(app.UIAxes, 'equal');

            % Mostrar la leyenda en el UIAxes
            legend(app.UIAxes, 'Trayectoria', 'Punto de impacto');

            % Calcular datos adicionales y mostrarlos en la interfaz
            % Altura máxima
            alturaMaxima = max(matrizY(:, 2));
            app.AlturaMaxEditField.Value = alturaMaxima;

            % Alcance máximo
            indiceAlcanceMaximo = find(matrizY(:, 2) <= 0, 1);
            alcanceMaximo = matrizX(indiceAlcanceMaximo, 2);
            app.AlcanceMxEditField.Value = alcanceMaximo;

            % Tiempo total
            tiempoTotal = matrizX(end, 1);
            app.TiempototalEditField.Value = tiempoTotal;

            %  Cálculo de la Velocidad Final

            velocidadF = velocidadInical - (9.8 * n);
            app.VelocidadEditField.Value = velocidadF;


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Modelación Volcán')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.ValoresInicialesPanel = uipanel(app.UIFigure);
            app.ValoresInicialesPanel.Title = 'Valores Iniciales';
            app.INICIOButton = uibutton(app.ValoresInicialesPanel, 'push');
            app.INICIOButton.ButtonPushedFcn = createCallbackFcn(app, @INICIOButtonPushed, true);
            app.INICIOButton.Text = 'INICIO';
            app.DiametroProyectilEditFieldLabel = uilabel(app.ValoresInicialesPanel);
            app.DiametroProyectilEditFieldLabel.Text = 'Diametro Proyectil';
            app.DiametroProyectilEditField = uieditfield(app.ValoresInicialesPanel, 'numeric');
            app.DiametroProyectilEditField.Value = 10;
            app.AnguloInicialLabel = uilabel(app.ValoresInicialesPanel);
            app.AnguloInicialLabel.Text = 'Angulo Inicial';
            app.AnguloInicialEditField = uieditfield(app.ValoresInicialesPanel, 'numeric');
            app.AnguloInicialEditField.Value = 60;
            app.VelocidadInicialEditFieldLabel = uilabel(app.ValoresInicialesPanel);
            app.VelocidadInicialEditFieldLabel.Text = 'Velocidad Inicial';
            app.VelocidadInicialEditField = uieditfield(app.ValoresInicialesPanel, 'numeric');
            app.VelocidadInicialEditField.Value = 20;
            app.DensidaddelAireEditFieldLabel = uilabel(app.ValoresInicialesPanel);
            app.DensidaddelAireEditFieldLabel.Text = 'Densidad del Aire';
            app.DensidaddelAireEditField = uieditfield(app.ValoresInicialesPanel, 'numeric');
            app.DensidaddelAireEditField.Value = 0.5;
            app.CoeficienteArrastreEditFieldLabel = uilabel(app.ValoresInicialesPanel);
            app.CoeficienteArrastreEditFieldLabel.Text = 'Coeficiente Arrastre';
            app.CoeficienteArrastreEditField = uieditfield(app.ValoresInicialesPanel, 'numeric');
            app.CoeficienteArrastreEditField.Value = 0.12;
            app.MasaEditFieldLabel = uilabel(app.ValoresInicialesPanel);
            app.MasaEditFieldLabel.Text = 'Masa';
            app.MasaEditField = uieditfield(app.ValoresInicialesPanel, 'numeric');
            app.MasaEditField.Value = 60;
            app.DelayEditFieldLabel = uilabel(app.ValoresInicialesPanel);
            app.DelayEditFieldLabel.Text = 'Delay';
            app.DelayEditField = uieditfield(app.ValoresInicialesPanel, 'numeric');
            app.DelayEditField.Value = 0.05;
            app.PasosEditFieldLabel = uilabel(app.ValoresInicialesPanel);
            app.PasosEditFieldLabel.Text = 'Pasos';
            app.PasosEditField = uieditfield(app.ValoresInicialesPanel, 'numeric');
            app.PasosEditField.Value = 20;
            app.PosXLabel = uilabel(app.ValoresInicialesPanel);
            app.PosXLabel.Text = 'Pos X';
            app.PosXEditField = uieditfield(app.ValoresInicialesPanel, 'numeric');
            app.PosXEditField.Value = 5;
            app.PosyEditFieldLabel = uilabel(app.ValoresInicialesPanel);
            app.PosyEditFieldLabel.Text = 'Pos y';
            app.PosyEditField = uieditfield(app.ValoresInicialesPanel, 'numeric');
            app.PosyEditField.Value = 6;
            app.DatosCalculadosPanel = uipanel(app.UIFigure);
            app.DatosCalculadosPanel.Title = 'Datos Calculados';
            app.TiempototalEditFieldLabel = uilabel(app.DatosCalculadosPanel);
            app.TiempototalEditFieldLabel.Text = 'Tiempo total';
            app.TiempototalEditField = uieditfield(app.DatosCalculadosPanel, 'numeric');
            app.AlturaMaxEditFieldLabel = uilabel(app.DatosCalculadosPanel);
            app.AlturaMaxEditFieldLabel.Text = 'Altura Max.';
            app.AlturaMaxEditField = uieditfield(app.DatosCalculadosPanel, 'numeric');
            app.AlcanceMxEditFieldLabel = uilabel(app.DatosCalculadosPanel);
            app.AlcanceMxEditFieldLabel.Text = 'Alcance Máx.';
            app.AlcanceMxEditField = uieditfield(app.DatosCalculadosPanel, 'numeric');
            app.VelocidadEditFieldLabel = uilabel(app.DatosCalculadosPanel);
            app.VelocidadEditFieldLabel.Text = 'Velocidad';
            app.VelocidadEditField = uieditfield(app.DatosCalculadosPanel, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = appReto

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end